classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SegmentedImageLabel             matlab.ui.control.Label
        EdgeImageLabel                  matlab.ui.control.Label
        OriginalImageLabel              matlab.ui.control.Label
        MetodeEdgeDetectionDropDown     matlab.ui.control.DropDown
        MetodeEdgeDetectionDropDownLabel  matlab.ui.control.Label
        SettingPanel                    matlab.ui.container.Panel
        FillThreasholdSlider            matlab.ui.control.Slider
        FillThreasholdSliderLabel       matlab.ui.control.Label
        DiskRadiusSlider                matlab.ui.control.Slider
        DiskRadiusSliderLabel           matlab.ui.control.Label
        FTLabel                         matlab.ui.control.Label
        DRLabel                         matlab.ui.control.Label
        LoGSizeSlider                   matlab.ui.control.Slider
        LoGSizeSliderLabel              matlab.ui.control.Label
        LoGStdSlider                    matlab.ui.control.Slider
        LoGStdSliderLabel               matlab.ui.control.Label
        LoGSizeLabel                    matlab.ui.control.Label
        LoGStdLabel                     matlab.ui.control.Label
        CannyStdSlider                  matlab.ui.control.Slider
        CannyStdSliderLabel             matlab.ui.control.Label
        CannyStdLabel                   matlab.ui.control.Label
        LaplaceOperatorVariantDropDown  matlab.ui.control.DropDown
        LaplaceOperatorVariantDropDownLabel  matlab.ui.control.Label
        CSlider                         matlab.ui.control.Slider
        CSliderLabel                    matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        TLabel                          matlab.ui.control.Label
        TSlider                         matlab.ui.control.Slider
        TSliderLabel                    matlab.ui.control.Label
        BrowseImageButton               matlab.ui.control.Button
        ImageSegmentationUsingEdgeDetectionTechniquesLabel  matlab.ui.control.Label
        SegmentedImageAxes              matlab.ui.control.UIAxes
        EdgeImageAxes                   matlab.ui.control.UIAxes
        OriImageAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img_ori = [];  % Initial definition for Original Image
        img_gray = [];  % Initial definition for Original Gray Image
        img_edge = []; % Initial definition for Edge Image
        img_segmented = []; % Initial definition for Segmented Image
    end
    
    methods (Access = public)
        
        function [original_img, gray_img] = load_image(~, path)
            % Read File Image
            original_img = imread(path);
        
            % Convert to grayscale image if original image is RGB
            if size(original_img, 3) == 3
                gray_img = rgb2gray(original_img);
            else
                gray_img = original_img;
            end
        end

        function segmented_img = segmentImage(~, img, edge_img, radius, fill_threshold)
            % Clear border from edge image
            mask = imclearborder(edge_img);
            % Close disconnected edges
            se = strel("disk", radius); % Morphological structuring element
            %mask= bwmorph(edge_img, "thin", inf);
            mask = imclose(mask, se); % Morphologically close image
            % Fill inside the edges
            mask = imfill(mask, 'holes'); % Fill image holes
            % Remove small objects
            mask = imopen(mask, strel(ones(3,3)));
            mask = bwareaopen(mask, fill_threshold); % Remove filled area under threshold
            segmented_img = img .* uint8(mask);
            % segmented_img = labeloverlay(img, mask);
        end
        
        function BW = apply_edge_detection(app, img, type, c, laplace_type, log_size, log_std, canny_std, T)
            if strcmp(type, 'Sobel')
                BW = apply_sobel_operator(app, img, c) >= T;
            elseif strcmp(type, 'Prewitt')
                BW = apply_prewitt_operator(app, img) >= T;
            elseif strcmp(type, 'Roberts')
                BW = apply_roberts_operator(app, img) >= T;
            elseif strcmp(type, 'Canny')
                BW = apply_canny_operator(app, img, canny_std);
            elseif strcmp(type, 'Laplace')
                BW = apply_laplacian_operator(app, img, laplace_type) >= T;
            elseif strcmp(type, 'LoG')
                BW = apply_log_operator(app, img, log_size, log_std) >= T;
            end
        end
        
        function [Sx, Sy] = get_sobel_mask(~, c)
            Sx = [-1 0 1; -c 0 c; -1 0 1];
            Sy = [1 c 1; 0 0 0; -1 -c -1];
        end
        
        function BW = apply_sobel_operator(app, img, c)
            [Sx, Sy] = get_sobel_mask(app, c);
            Jx = conv2(double(img), double(Sx), 'same');
            Jy = conv2(double(img), double(Sy), 'same');
            Jedge = sqrt(Jx.^2 + Jy.^2);
            BW = uint8(Jedge);
        end
        
        function [Px, Py] = get_prewitt_mask(app)
            % Persamaan gradien pada operator Prewitt sama seperti operator Sobel, tetapi menggunakan nilai c = 1:
            [Px, Py] = get_sobel_mask(app, 1);
        end
        
        function BW = apply_prewitt_operator(app, img)
            [Px, Py] = get_prewitt_mask(app);
            Jx = conv2(double(img), double(Px), 'same');
            Jy = conv2(double(img), double(Py), 'same');
            Jedge = sqrt(Jx.^2 + Jy.^2);
            BW = uint8(Jedge);
        end
        
        function [Rx, Ry] = get_roberts_mask(~)
            Rx = [1 0; 0 -1];
            Ry = [0 1; -1 0];
        end
        
        function BW = apply_roberts_operator(app, img)
            [Rx, Ry] = get_roberts_mask(app);
            Jx = conv2(double(img), double(Rx), 'same');
            Jy = conv2(double(img), double(Ry), 'same');
            Jedge = sqrt(Jx.^2 + Jy.^2);
            BW = uint8(Jedge);
        end
        
        function H = get_laplacian_mask(~, type)
            if strcmp(type, 'Original')
                % Filter mask used to implement the digital Laplacian (Original)
                H = [0 1 0; 1 -4 1; 0 1 0];
            elseif strcmp(type, 'Diagonal')
                % Filter mask used to implement an extension of this equation that
                % includes the diagonal
                H = [1 1 1; 1 -8 1; 1 1 1];
            end
        end
        
        function BW = apply_laplacian_operator(app, img, type)
            H = get_laplacian_mask(app, type);
            J = conv2(double(img), double(H), "same");
            BW = uint8(J);
        end
        
        function H = get_log_mask(~, size, std)
            H = fspecial('log', size, std);
        end
        
        function BW = apply_log_operator(app, img, size, std)
            H = get_log_mask(app, size, std);
            J = conv2(double(img), double(H), "same");
            BW = uint8(J);
        end
        
        function BW = apply_canny_operator(~, img, std)
            BW = edge(img, "canny", [], std);
        end

        function RenderOriginalImage(app)
            if isempty(app.img_ori) == 0
                imshow(app.img_ori, 'Parent', app.OriImageAxes);
                app.OriImageAxes.XLim = [0 width(app.img_ori)];
                app.OriImageAxes.YLim = [0 height(app.img_ori)];
            end
        end

        function RenderEdgeImage(app)
            if isempty(app.img_edge) == 0
                imshow(app.img_edge, 'Parent', app.EdgeImageAxes);
                app.EdgeImageAxes.XLim = [0 width(app.img_edge)];
                app.EdgeImageAxes.YLim = [0 height(app.img_edge)];
            end
        end

        function RenderSegmentedImage(app)
            if isempty(app.img_segmented) == 0
                imshow(app.img_segmented, 'Parent', app.SegmentedImageAxes);
                app.SegmentedImageAxes.XLim = [0 width(app.img_segmented)];
                app.SegmentedImageAxes.YLim = [0 height(app.img_segmented)];
            end
        end

        function EdgeProcessing(app)
            if isempty(app.img_ori) == 0
                edgeType = app.MetodeEdgeDetectionDropDown.Value;
                c = round(app.CSlider.Value);
                laplace_type = app.LaplaceOperatorVariantDropDown.Value;
                log_size = round(app.LoGSizeSlider.Value);
                log_std = round(app.LoGStdSlider.Value, 1);
                canny_std = round(app.CannyStdSlider.Value, 1);
                T = round(app.TSlider.Value);
                app.img_edge = apply_edge_detection(app, app.img_gray, edgeType, c, laplace_type, log_size, log_std, canny_std, T);
                RenderEdgeImage(app);
            end
        end

        function ApplySegmentImage(app)
            if isempty(app.img_ori) == 0
                radius = round(app.DiskRadiusSlider.Value);
                fill_threshold = round(app.FillThreasholdSlider.Value);
                app.img_segmented = segmentImage(app, app.img_ori, app.img_edge, radius, fill_threshold);
                RenderSegmentedImage(app);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.bmp;*.oct;*.tif;*.jpeg'}, 'Select Citra Input');
            file = [path, file];
            [app.img_ori, app.img_gray] = load_image(app, file);
            RenderOriginalImage(app);
            EdgeProcessing(app);
            ApplySegmentImage(app);
        end

        % Value changed function: MetodeEdgeDetectionDropDown
        function MetodeEdgeDetectionDropDownValueChanged(app, event)
            value = app.MetodeEdgeDetectionDropDown.Value;
            set(app.TSlider, 'Enable', 'on');
            set(app.TSliderLabel, 'Enable', 'on');
            set(app.TLabel, 'Enable', 'on');
            set(app.CSlider, 'Visible', 'off');
            set(app.CSliderLabel, 'Visible', 'off');
            set(app.CLabel, 'Visible', 'off');
            set(app.LaplaceOperatorVariantDropDown, 'Visible', 'off');
            set(app.LaplaceOperatorVariantDropDownLabel, 'Visible', 'off');
            set(app.CannyStdSlider, 'Visible', 'off');
            set(app.CannyStdSliderLabel, 'Visible', 'off');
            set(app.CannyStdLabel, 'Visible', 'off');
            set(app.LoGSizeSlider, 'Visible', 'off');
            set(app.LoGSizeSliderLabel, 'Visible', 'off');
            set(app.LoGSizeLabel, 'Visible', 'off');
            set(app.LoGStdSlider, 'Visible', 'off');
            set(app.LoGStdSliderLabel, 'Visible', 'off');
            set(app.LoGStdLabel, 'Visible', 'off');
            if strcmp(value, 'Sobel')
                set(app.CSlider, 'Visible', 'on');
                set(app.CLabel, 'Visible', 'on');
                set(app.CSliderLabel, 'Visible', 'on');
            elseif strcmp(value, 'Laplace')
                set(app.LaplaceOperatorVariantDropDown, 'Visible', 'on');
                set(app.LaplaceOperatorVariantDropDownLabel, 'Visible', 'on');
            elseif strcmp(value, 'Canny')
                set(app.TSlider, 'Enable', 'off');
                set(app.TSliderLabel, 'Enable', 'off');
                set(app.TLabel, 'Enable', 'off');
                set(app.CannyStdSlider, 'Visible', 'on');
                set(app.CannyStdSliderLabel, 'Visible', 'on');
                set(app.CannyStdLabel, 'Visible', 'on');
            elseif strcmp(value, 'LoG')
                set(app.LoGSizeSlider, 'Visible', 'on');
                set(app.LoGSizeSliderLabel, 'Visible', 'on');
                set(app.LoGSizeLabel, 'Visible', 'on');
                set(app.LoGStdSlider, 'Visible', 'on');
                set(app.LoGStdSliderLabel, 'Visible', 'on');
                set(app.LoGStdLabel, 'Visible', 'on');
            end
            EdgeProcessing(app);
            ApplySegmentImage(app);
        end

        % Value changing function: TSlider
        function TSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.TLabel.Text = string(changingValue);
        end

        % Value changing function: CSlider
        function CSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.CLabel.Text = string(changingValue);
        end

        % Value changing function: CannyStdSlider
        function CannyStdSliderValueChanging(app, event)
            changingValue = round(event.Value, 1);
            app.CannyStdLabel.Text = string(changingValue);
        end

        % Value changing function: LoGStdSlider
        function LoGStdSliderValueChanging(app, event)
            changingValue = round(event.Value, 1);
            app.LoGStdLabel.Text = string(changingValue);
        end

        % Value changing function: LoGSizeSlider
        function LoGSizeSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.LoGSizeLabel.Text = string(changingValue);
        end

        % Value changed function: TSlider
        function TSliderValueChanged(app, event)
            EdgeProcessing(app);
            ApplySegmentImage(app);
        end

        % Value changed function: CSlider
        function CSliderValueChanged(app, event)
            EdgeProcessing(app);
            ApplySegmentImage(app);
        end

        % Value changed function: LaplaceOperatorVariantDropDown
        function LaplaceOperatorVariantDropDownValueChanged(app, event)
            EdgeProcessing(app);
            ApplySegmentImage(app);
        end

        % Value changed function: CannyStdSlider
        function CannyStdSliderValueChanged(app, event)
            EdgeProcessing(app);
            ApplySegmentImage(app);
        end

        % Value changed function: LoGStdSlider
        function LoGStdSliderValueChanged(app, event)
            EdgeProcessing(app);
            ApplySegmentImage(app);
        end

        % Value changed function: LoGSizeSlider
        function LoGSizeSliderValueChanged(app, event)
            EdgeProcessing(app);
            ApplySegmentImage(app);
        end

        % Value changing function: DiskRadiusSlider
        function DiskRadiusSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.DRLabel.Text = string(changingValue);
        end

        % Value changing function: FillThreasholdSlider
        function FillThreasholdSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.FTLabel.Text = string(changingValue);
        end

        % Value changed function: DiskRadiusSlider
        function DiskRadiusSliderValueChanged(app, event)
            ApplySegmentImage(app);
        end

        % Value changed function: FillThreasholdSlider
        function FillThreasholdSliderValueChanged(app, event)
            ApplySegmentImage(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriImageAxes = uiaxes(app.UIFigure);
            app.EdgeImageAxes = uiaxes(app.UIFigure);
            app.SegmentedImageAxes = uiaxes(app.UIFigure);
            app.ImageSegmentationUsingEdgeDetectionTechniquesLabel = uilabel(app.UIFigure);
            app.ImageSegmentationUsingEdgeDetectionTechniquesLabel.Text = 'Image Segmentation Using Edge Detection Techniques';
            app.BrowseImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.SettingPanel = uipanel(app.UIFigure);
            app.SettingPanel.Title = 'Setting';
            app.TSliderLabel = uilabel(app.SettingPanel);
            app.TSliderLabel.Text = 'T';
            app.TSlider = uislider(app.SettingPanel);
            app.TSlider.Limits = [0 255];
            app.TSlider.ValueChangedFcn = createCallbackFcn(app, @TSliderValueChanged, true);
            app.TSlider.ValueChangingFcn = createCallbackFcn(app, @TSliderValueChanging, true);
            app.TSlider.Value = 100;
            app.TLabel = uilabel(app.SettingPanel);
            app.TLabel.Text = '100';
            app.CLabel = uilabel(app.SettingPanel);
            app.CLabel.Text = '2';
            app.CSliderLabel = uilabel(app.SettingPanel);
            app.CSliderLabel.Text = 'C';
            app.CSlider = uislider(app.SettingPanel);
            app.CSlider.Limits = [0 25];
            app.CSlider.ValueChangedFcn = createCallbackFcn(app, @CSliderValueChanged, true);
            app.CSlider.ValueChangingFcn = createCallbackFcn(app, @CSliderValueChanging, true);
            app.CSlider.Value = 2;
            app.LaplaceOperatorVariantDropDownLabel = uilabel(app.SettingPanel);
            app.LaplaceOperatorVariantDropDownLabel.Visible = 'off';
            app.LaplaceOperatorVariantDropDownLabel.Text = 'Laplace Operator Variant';
            app.LaplaceOperatorVariantDropDown = uidropdown(app.SettingPanel);
            app.LaplaceOperatorVariantDropDown.Items = {'Original', 'Diagonal'};
            app.LaplaceOperatorVariantDropDown.ValueChangedFcn = createCallbackFcn(app, @LaplaceOperatorVariantDropDownValueChanged, true);
            app.LaplaceOperatorVariantDropDown.Visible = 'off';
            app.LaplaceOperatorVariantDropDown.Value = 'Original';
            app.CannyStdLabel = uilabel(app.SettingPanel);
            app.CannyStdLabel.Visible = 'off';
            app.CannyStdLabel.Text = '0.5';
            app.CannyStdSliderLabel = uilabel(app.SettingPanel);
            app.CannyStdSliderLabel.Visible = 'off';
            app.CannyStdSliderLabel.Text = 'Canny Std';
            app.CannyStdSlider = uislider(app.SettingPanel);
            app.CannyStdSlider.Limits = [0 5];
            app.CannyStdSlider.ValueChangedFcn = createCallbackFcn(app, @CannyStdSliderValueChanged, true);
            app.CannyStdSlider.ValueChangingFcn = createCallbackFcn(app, @CannyStdSliderValueChanging, true);
            app.CannyStdSlider.Visible = 'off';
            app.CannyStdSlider.Value = 0.5;
            app.LoGStdLabel = uilabel(app.SettingPanel);
            app.LoGStdLabel.Visible = 'off';
            app.LoGStdLabel.Text = '0.5';
            app.LoGSizeLabel = uilabel(app.SettingPanel);
            app.LoGSizeLabel.Visible = 'off';
            app.LoGSizeLabel.Text = '5';
            app.LoGStdSliderLabel = uilabel(app.SettingPanel);
            app.LoGStdSliderLabel.Visible = 'off';
            app.LoGStdSliderLabel.Text = 'LoG Std';
            app.LoGStdSlider = uislider(app.SettingPanel);
            app.LoGStdSlider.Limits = [0 5];
            app.LoGStdSlider.ValueChangedFcn = createCallbackFcn(app, @LoGStdSliderValueChanged, true);
            app.LoGStdSlider.ValueChangingFcn = createCallbackFcn(app, @LoGStdSliderValueChanging, true);
            app.LoGStdSlider.Visible = 'off';
            app.LoGStdSlider.Value = 0.5;
            app.LoGSizeSliderLabel = uilabel(app.SettingPanel);
            app.LoGSizeSliderLabel.Visible = 'off';
            app.LoGSizeSliderLabel.Text = 'LoG Size';
            app.LoGSizeSlider = uislider(app.SettingPanel);
            app.LoGSizeSlider.Limits = [0 25];
            app.LoGSizeSlider.ValueChangedFcn = createCallbackFcn(app, @LoGSizeSliderValueChanged, true);
            app.LoGSizeSlider.ValueChangingFcn = createCallbackFcn(app, @LoGSizeSliderValueChanging, true);
            app.LoGSizeSlider.Visible = 'off';
            app.LoGSizeSlider.Value = 5;
            app.DRLabel = uilabel(app.SettingPanel);
            app.DRLabel.Text = '10';
            app.FTLabel = uilabel(app.SettingPanel);
            app.FTLabel.Text = '10';
            app.DiskRadiusSliderLabel = uilabel(app.SettingPanel);
            app.DiskRadiusSliderLabel.Text = 'Disk Radius';
            app.DiskRadiusSlider = uislider(app.SettingPanel);
            app.DiskRadiusSlider.ValueChangedFcn = createCallbackFcn(app, @DiskRadiusSliderValueChanged, true);
            app.DiskRadiusSlider.ValueChangingFcn = createCallbackFcn(app, @DiskRadiusSliderValueChanging, true);
            app.DiskRadiusSlider.Value = 10;
            app.FillThreasholdSliderLabel = uilabel(app.SettingPanel);
            app.FillThreasholdSliderLabel.Text = 'Fill Threashold';
            app.FillThreasholdSlider = uislider(app.SettingPanel);
            app.FillThreasholdSlider.Limits = [0 2000];
            app.FillThreasholdSlider.ValueChangedFcn = createCallbackFcn(app, @FillThreasholdSliderValueChanged, true);
            app.FillThreasholdSlider.ValueChangingFcn = createCallbackFcn(app, @FillThreasholdSliderValueChanging, true);
            app.FillThreasholdSlider.Value = 10;
            app.MetodeEdgeDetectionDropDownLabel = uilabel(app.UIFigure);
            app.MetodeEdgeDetectionDropDownLabel.Text = 'Metode Edge Detection';
            app.MetodeEdgeDetectionDropDown = uidropdown(app.UIFigure);
            app.MetodeEdgeDetectionDropDown.Items = {'Sobel', 'Prewitt', 'Roberts', 'Canny', 'Laplace', 'LoG'};
            app.MetodeEdgeDetectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MetodeEdgeDetectionDropDownValueChanged, true);
            app.MetodeEdgeDetectionDropDown.Value = 'Sobel';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.EdgeImageLabel = uilabel(app.UIFigure);
            app.EdgeImageLabel.Text = 'Edge Image';
            app.SegmentedImageLabel = uilabel(app.UIFigure);
            app.SegmentedImageLabel.Text = 'Segmented Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end